classdef Annotator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        CurrentclipPanel            matlab.ui.container.Panel
        CurrentClipTable            matlab.ui.control.Table
        AnnotationFilesPanel        matlab.ui.container.Panel
        IncompleteTable             matlab.ui.control.Table
        CompleteTable               matlab.ui.control.Table
        CompleteannotationsLabel    matlab.ui.control.Label
        IncompleteannotationsLabel  matlab.ui.control.Label
        AnnotationprogressPanel     matlab.ui.container.Panel
        EstAccText                  matlab.ui.control.Label
        EstAccLabel                 matlab.ui.control.Label
        IncompleteTotalTimeLabel    matlab.ui.control.Label
        CompleteTotalTimeLabel      matlab.ui.control.Label
        ProportionAnnotatedLabel    matlab.ui.control.Label
        ProportionAnnotatedText     matlab.ui.control.Label
        IncompleteTotalTimeText     matlab.ui.control.Label
        CompleteTotalTimeText       matlab.ui.control.Label
        ActionsPanel                matlab.ui.container.Panel
        MarkIncompleteButton        matlab.ui.control.Button
        MarkCompleteButton          matlab.ui.control.Button
        SaveProgressButton          matlab.ui.control.Button
        LoadVideoButton             matlab.ui.control.Button
        BehaviorlabelsPanel         matlab.ui.container.Panel
        BehaviorLabelsTable         matlab.ui.control.Table
        ProjectInformationPanel     matlab.ui.container.Panel
        ProjectNameText             matlab.ui.control.Label
        ProjectNameLabel            matlab.ui.control.Label
        TotalDurationText           matlab.ui.control.Label
        TotalDurationLabel          matlab.ui.control.Label
        NumVideosText               matlab.ui.control.Label
        NumVideosLabel              matlab.ui.control.Label
        NumClipsText                matlab.ui.control.Label
        NumClipsLabel               matlab.ui.control.Label
        AvgVideoLengthText          matlab.ui.control.Label
        AvgVideoLengthLabel         matlab.ui.control.Label
        AvgClipLengthText           matlab.ui.control.Label
        AvgClipLengthLabel          matlab.ui.control.Label
        TotalFramesText             matlab.ui.control.Label
        TotalFramesLabel            matlab.ui.control.Label
        CurrentBehaviorLabel        matlab.ui.control.Label
        CurrentBehaviorText         matlab.ui.control.Label
        CurrentSpeedText            matlab.ui.control.Label
        CurrentSpeedLabel           matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
        Ethogram                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        VerboseLevel
        
        ClipVideoReader
        FrameRate
        ClipStartIndex
        ClipEndIndex
        ClipNFrames
        BehaviorArray
        ClipStartTime
        ClipEndTime
        
        CurrentSpeed
        CurrentFrame
        CurrentBehavior
        
        CurrentClipNumber
        SelectedClipNumber
        ReviewClipTable
        ProjectPath

        AllowKeyPressControl = true

        ConfigFile
        BehaviorUITableSelection

        Keys
    end
    
    methods (Access = private)
        
        function ChangePlaybackSpeed(app, speed)
            mins = minute(datetime('now'));
            secs = second(datetime('now'));
            fprintf('   %0.0f:%2.3f, %d, %0.0f, %d\n', mins, secs, app.CurrentSpeed, speed, app.CurrentFrame)                

            if speed == 0
                app.CurrentSpeed = 0;
                app.CurrentSpeedText.Text = num2str(app.CurrentSpeed);
                
                CreateEthogramPlot(app)
                frame = read(app.ClipVideoReader, app.CurrentFrame);
                set(app.UIAxes.Children, 'CData', frame)
                drawnow limitrate
            else
                drawnow;
                app.CurrentSpeed = speed;
                app.CurrentSpeedText.Text = num2str(app.CurrentSpeed);
                
                tic
                startVideoTime = app.ClipVideoReader.CurrentTime;
                startFrameIdx = app.CurrentFrame;

                [ts, frameNos] = GetTSAndFrameNos(app.ReviewClipTable);
                while app.CurrentSpeed == speed

                    functionTimeElapsed = toc;
                    functionTargetTime = speed * functionTimeElapsed + startVideoTime;
                    [currTs, currClipClosestIndex] = min(abs(ts - functionTargetTime));
                    app.CurrentFrame = frameNos(currClipClosestIndex);

                    if functionTargetTime < app.ClipStartTime
                        
                        app.ClipVideoReader.CurrentTime = app.ClipStartTime;
                        app.CurrentFrame = app.ClipStartIndex;

                        if ~isempty(app.CurrentBehavior)
                            startIdx = startFrameIdx - app.ClipStartIndex + 1;
                            endIdx = app.CurrentFrame - app.ClipStartIndex + 1;       
                            
                            if app.CurrentSpeed > 0
                                indices = startIdx:endIdx;
                            else
                                indices = startIdx:-1:endIdx;
                            end

                            app.ReviewClipTable = app.ReviewClipTable.SetBehavior(indices, app.CurrentBehavior);
                        end

                        ChangePlaybackSpeed(app, 0)
                        drawnow limitrate

                    elseif functionTargetTime > app.ClipEndTime
                        app.ClipVideoReader.CurrentTime = app.ClipEndTime;
                        app.CurrentFrame = app.ClipEndIndex;

                        if ~isempty(app.CurrentBehavior)
                            startIdx = startFrameIdx - app.ClipStartIndex + 1;
                            endIdx = app.CurrentFrame - app.ClipStartIndex + 1;       
                            
                            if app.CurrentSpeed > 0
                                indices = startIdx:endIdx;
                            else
                                indices = startIdx:-1:endIdx;
                            end

                            app.ReviewClipTable = app.ReviewClipTable.SetBehavior(indices, app.CurrentBehavior);
                        end

                        ChangePlaybackSpeed(app, 0)
                        drawnow limitrate

                    else
                        if ~isempty(app.CurrentBehavior)
                            startIdx = startFrameIdx - app.ClipStartIndex + 1;
                            endIdx = app.CurrentFrame - app.ClipStartIndex + 1;       
                            
                            if app.CurrentSpeed > 0
                                indices = startIdx:endIdx;
                            else
                                indices = startIdx:-1:endIdx;
                            end

                            app.ReviewClipTable = app.ReviewClipTable.SetBehavior(indices, app.CurrentBehavior);
                        end

                        frame = read(app.ClipVideoReader, app.CurrentFrame);
                        
                        set(app.UIAxes.Children, 'CData', frame)
                        startFrameIdx = app.CurrentFrame;

                        CreateEthogramPlot(app)
                        drawnow; 
                    end
                end 
            end
        end
        
        function CreateEthogramPlot(app)
            cla(app.Ethogram)
            
            keyT = app.Keys.GetKeyTable();
            labels = keyT.Behavior;
            colors = parula(length(labels));

            app.Ethogram.YTickLabel = labels;
            app.Ethogram.YTick = 1:length(labels);

            app.Ethogram.YLim = [0, length(labels)+1];
            
            if isempty(app.CurrentClipNumber)
                return
            end

            xline(app.Ethogram, app.CurrentFrame, 'Color', 'black', 'LineWidth', 2);
            app.Ethogram.XLim = [app.ClipStartIndex, app.ClipEndIndex];

            hold(app.Ethogram,'on');
            for i = 1:length(labels)
                indices = app.ReviewClipTable.GetIndicesWhereBehaviorOccurs(labels{i});

                if isempty(indices)
                    continue
                end

                indices = Annotator.CreateNanPlot(indices);

                currY = repmat(i, size(indices, 1), size(indices, 2));
                plot(app.Ethogram, indices, currY, 'LineWidth', 3, 'Color', colors(i,:));
            end
            
            hold(app.Ethogram,'off');
        end
        
        function SetCurrentBehavior(app, behavior)
            app.CurrentBehavior = behavior;

            keyT = app.Keys.GetKeyTable();
            labels = keyT.Behavior;
            colors = parula(length(labels));

            if ~isempty(app.CurrentBehavior)
                app.CurrentBehaviorLabel.Text = app.CurrentBehavior;
                behavIndex = find(strcmp(app.CurrentBehavior, labels));
                set(app.CurrentBehaviorLabel, 'FontColor', colors(behavIndex,:))
            else
                app.CurrentBehaviorLabel.Text = '';
            end
        end

        function IncreasePlaybackSpeed(app)
            if isempty(app.CurrentClipNumber)
                return
            end

            if app.CurrentSpeed == -30
                ChangePlaybackSpeed(app, -10)
            elseif app.CurrentSpeed == -10
                ChangePlaybackSpeed(app, -5)
            elseif app.CurrentSpeed == -5
                ChangePlaybackSpeed(app, -2)
            elseif app.CurrentSpeed == -2
                ChangePlaybackSpeed(app, -1)
            elseif app.CurrentSpeed == -1
                ChangePlaybackSpeed(app, -0.5)
            elseif app.CurrentSpeed == -0.5
                ChangePlaybackSpeed(app, -0.25)    
            elseif app.CurrentSpeed == -0.25
                ChangePlaybackSpeed(app, 0)
            elseif app.CurrentSpeed == 0
                ChangePlaybackSpeed(app, 0.25)
            elseif app.CurrentSpeed == 0.25
                ChangePlaybackSpeed(app, 0.5)
            elseif app.CurrentSpeed == 0.5
                ChangePlaybackSpeed(app, 1)
            elseif app.CurrentSpeed == 1
                ChangePlaybackSpeed(app, 2)
            elseif app.CurrentSpeed == 2
                ChangePlaybackSpeed(app, 5)
            elseif app.CurrentSpeed == 5
                ChangePlaybackSpeed(app, 10)
            elseif app.CurrentSpeed == 10
                ChangePlaybackSpeed(app, 30)
            elseif app.CurrentSpeed == 30
                return
            end

            drawnow limitrate
        end
        
        function DecreasePlaybackSpeed(app)
            if isempty(app.CurrentClipNumber)
                return
            end

            if app.CurrentSpeed == 30
                ChangePlaybackSpeed(app, 10)
            elseif app.CurrentSpeed == 10
                ChangePlaybackSpeed(app, 5)
            elseif app.CurrentSpeed == 5
                ChangePlaybackSpeed(app, 2)
            elseif app.CurrentSpeed == 2
                ChangePlaybackSpeed(app, 1)    
            elseif app.CurrentSpeed == 1
                ChangePlaybackSpeed(app, 0.5)
            elseif app.CurrentSpeed == 0.5
                ChangePlaybackSpeed(app, 0.25)                
            elseif app.CurrentSpeed == 0.25
                ChangePlaybackSpeed(app, 0)
            elseif app.CurrentSpeed == 0
                ChangePlaybackSpeed(app, -0.25)
            elseif app.CurrentSpeed == -0.25
                ChangePlaybackSpeed(app, -0.5)
            elseif app.CurrentSpeed == -0.5
                ChangePlaybackSpeed(app, -1)
            elseif app.CurrentSpeed == -1
                ChangePlaybackSpeed(app, -2)
            elseif app.CurrentSpeed == -2
                ChangePlaybackSpeed(app, -5)
            elseif app.CurrentSpeed == -5
                ChangePlaybackSpeed(app, -10)
            elseif app.CurrentSpeed == -10
                ChangePlaybackSpeed(app, -30)
            elseif app.CurrentSpeed == -30
                return 
            end

            drawnow limitrate
        end
        
        function UpdateAnnotationStatusTables(app)
            incDur = app.ReviewClipTable.GetTotalDuration('incomplete');
            app.IncompleteTotalTimeLabel.Text = char(incDur);

            complDur = app.ReviewClipTable.GetTotalDuration('complete');
            app.CompleteTotalTimeLabel.Text = char(complDur);

            propAnnot = seconds(complDur) / (seconds(incDur) + seconds(complDur));
            app.ProportionAnnotatedLabel.Text = sprintf('%0.1f %%', propAnnot*100);

            currT = app.ReviewClipTable.GetClipTable('incomplete');
            UpdateAnnotationTable(app, app.IncompleteTable, currT, true)
            
            estAcc = Annotator.GetEstAcc(currT);
            app.EstAccLabel.Text = sprintf('%0.1f %%', estAcc*100);
            
            currT = app.ReviewClipTable.GetClipTable('complete');
            UpdateAnnotationTable(app, app.CompleteTable, currT, true)

            currClip = app.ReviewClipTable.GetCurrentClipTable();
            UpdateAnnotationTable(app, app.CurrentClipTable, currClip, false)
        end
        
        function UpdateAnnotationTable(app, uiTable, clipT, addTStyle)

            if size(clipT, 1) == 0
                nCols = length(uiTable.ColumnName);
                uiTable.Data = repmat({''}, 1, nCols);
                return
            end

            hasScores = any(strcmpi('Score', clipT.Properties.VariableNames));
            
            if hasScores
                uiTable.ColumnName = {'Clip No.'; 'Video'; 'Duration'; 'Score'};
            else
                uiTable.ColumnName = {'Clip No.'; 'Video'; 'Duration'};
            end

            clipNums = Annotator.FormatNumbersForTable(clipT.ClipNumber);
            vids = clipT.Video;
            durationsText = Annotator.GetClipDurations(clipT);
            if hasScores
                scores = Annotator.FormatNumbersForTable(clipT.Score);
                uiTable.Data = [clipNums, vids, durationsText, scores];
            else
                uiTable.Data = [clipNums, vids, durationsText];
            end
        end


        function ChangeBehaviorKey(app, behavior, newKey)
            app.Keys = app.Keys.ChangeBehaviorKey(behavior, newKey);
            app.UpdateBehaviors()
        end


        function UpdateBehaviors(app)
            keyT = app.Keys.GetKeyTable();
            app.BehaviorLabelsTable.Data = [keyT.Behavior, cellstr(keyT.Key)];
            app.CreateEthogramPlot()
        end
        
        function PopulateProjectInformation(app)
            app.ProjectNameLabel.Text = app.ConfigFile.GetParams('ProjectName');

            totalDuration = app.ReviewClipTable.GetTotalDuration();
            app.TotalDurationLabel.Text = char(totalDuration);
            
            clipT = app.ReviewClipTable.GetClipTable();
            nVideos = length(unique(clipT.Video));
            app.NumVideosLabel.Text = sprintf('%d', nVideos);

            avgLen = seconds(totalDuration) / nVideos;
            avgLen = seconds(avgLen);
            if hours(avgLen) < 1
                avgLen.Format = 'mm:ss';
            else
                avgLen.Format = 'hh:mm:ss';
            end
            app.AvgVideoLengthLabel.Text = char(avgLen);


            nClips = size(clipT, 1);
            app.NumClipsLabel.Text = sprintf('%d', nClips);

            avgLen = seconds(totalDuration) / nClips;
            avgLen = seconds(avgLen);
            if hours(avgLen) < 1
                avgLen.Format = 'mm:ss';
            else
                avgLen.Format = 'hh:mm:ss';
            end
            app.AvgClipLengthLabel.Text = char(avgLen);

            
            totalFrames = Annotator.GetTotalFrames(clipT);
            app.TotalFramesLabel.Text = sprintf('%d', totalFrames);

        end
        
        function SaveProgress(app)
            if app.VerboseLevel > 0
                fprintf('Saving progress... ')
            end
            ClipTable = app.ReviewClipTable.GetClipTable();
            project = DeepActionProject(app.ProjectPath);
            project.UpdateAnnotationsFromClipTable(ClipTable)

            savePath = fullfile(app.ProjectPath, 'annotations', 'ClipTable.mat');
            save(savePath, 'ClipTable', '-v7.3','-nocompression');
% 
            app.Keys.Save();
            if app.VerboseLevel > 0
                fprintf('complete\n')
            end
        end
    end
    
    methods(Static)
        function durationsText = GetClipDurations(clipT)
            
            durationsText = {};
            for i = 1:size(clipT, 1)
                startTime = clipT.Annotations{i}.TimeStamp(1);
                endTime = clipT.Annotations{i}.TimeStamp(end);
                currDur = endTime - startTime;
                currDur = seconds(currDur);
                currDur.Format = 'mm:ss';
                durationsText = [durationsText; char(currDur)];
            end
        end
        
        function estAcc = GetEstAcc(clipT)

            if ~strcmp('Score', clipT.Properties.VariableNames)
                estAcc = [];
                return
            end

            nFrames = [];
            for i = 1:size(clipT, 1)
                startIdx = clipT.Annotations{i}.Frame(1);
                endIdx = clipT.Annotations{i}.Frame(end);
                nFrames = [nFrames; endIdx - startIdx];
            end

            weights = nFrames ./ sum(nFrames);
            weightedScores = clipT.Score .* weights;
            estAcc = sum(weightedScores);
        end

        function formattedNums = FormatNumbersForTable(numbers)
            formattedNums = {}; 
            for i = 1:length(numbers)
                if max(numbers) <= 1
                    formattedNums = [formattedNums; sprintf('%0.3f', numbers(i))];
                else
                    formattedNums = [formattedNums; sprintf('%d', numbers(i))];
                end
            end
        end

        function totalFrames = GetTotalFrames(clipT)
            totalFrames = 0;

            for i = 1:size(clipT, 1)
                totalFrames = totalFrames + size(clipT.Annotations{i}, 1);
            end
        end

        function indices = CreateNanPlot(indices)
            isDiff = find(indices(1:end-1)+1 ~= indices(2:end));

            if isempty(isDiff)
                return
            end

            for i = length(isDiff):-1:1
                indices = [indices(1:isDiff(i)); nan; indices(isDiff(i)+1:end)];
            end
        end

        function clipNum = GetClipNumberByUITableRow(uiTable, row)
            tableData = get(uiTable, 'Data');
            clipNum = tableData(row, 1);
            clipNum = str2double(clipNum);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, project, varargin)
            if nargin == 2
                clipT = project.ClipTable;
            else
                clipT = varargin{1};
            end

            
            app.VerboseLevel = project.ConfigFile.GetParams('VerboseLevel');
            multicam = project.ConfigFile.GetParams('MultipleCameras');
            primaryCamera = project.ConfigFile.GetParams('PrimaryCamera');

            currAnnotT = clipT.Annotations;
            if multicam
                clipT.Annotations.TimeStamps = clipT.Annotations.TimeStamp.(primaryCamera);
            else
                for i = 1:size(currAnnotT, 1)
                    clipT.Annotations{i}.TimeStamp = double(clipT.Annotations{i}.TimeStamp.TimeStamp);
                end
            end

            app.ReviewClipTable = ReviewClipTable(clipT);
            app.ProjectPath = project.ProjectPath;
            app.ConfigFile = project.ConfigFile;
            app.Keys = AnnotationKeys(project, 'VerboseLevel', app.VerboseLevel);
            app.Keys.Update();

            UpdateAnnotationStatusTables(app)
            UpdateBehaviors(app)
            PopulateProjectInformation(app)

            set(app.BehaviorLabelsTable,'ColumnWidth', {'auto', 40})
            set(app.Ethogram, 'box', 'off');
            
            set(app.BehaviorLabelsTable,'ColumnEditable',true(1,2))
            
            set(app.MarkCompleteButton, 'Enable', 'off')
            set(app.MarkIncompleteButton, 'Enable', 'off')

            CreateEthogramPlot(app)
        end

        % Button pushed function: LoadVideoButton
        function LoadVideoButtonPushed(app, event)

            set(app.LoadVideoButton, 'Enable', 'on')

            app.SetCurrentBehavior([]);

            app.CurrentClipNumber = app.SelectedClipNumber;
            app.ReviewClipTable = app.ReviewClipTable.SetCurrentClip(app.CurrentClipNumber);

            currClipT = app.ReviewClipTable.GetCurrentClipTable();
            app.UpdateAnnotationStatusTables()

            currVideo = currClipT.Video{1};
            videoFolder = fullfile(app.ProjectPath, 'videos', currVideo);
            [file, ~] = GetValidFolderFiles(videoFolder);
            videoPath = fullfile(file.folder{1}, file.name{1});
            app.ClipVideoReader = VideoReader(videoPath);
            
            currAnnotT = app.ReviewClipTable.GetCurrentAnnotation();
            app.ClipStartIndex = currAnnotT.Frame(1);
            app.ClipEndIndex = currAnnotT.Frame(end);
            app.CurrentSpeed = 0;
            app.CurrentFrame = app.ClipStartIndex;
            
            frame = read(app.ClipVideoReader, app.CurrentFrame);
            app.ClipStartTime = app.ClipVideoReader.CurrentTime;
            app.ClipEndTime = currAnnotT.TimeStamp(end);
            imshow(frame, 'parent', app.UIAxes);
            
            CreateEthogramPlot(app)
            set(app.MarkCompleteButton, 'Enable', 'on')
            set(app.MarkIncompleteButton, 'Enable', 'on')

            if app.VerboseLevel > 0
                fprintf('Clip %d... loaded\n', app.CurrentClipNumber)
            end
        end

        % Callback function
        function PlayButtonPushed(app, event)
            IncreasePlaybackSpeed(app)
        end

        % Callback function
        function PauseButtonPushed(app, event)
            ChangePlaybackSpeed(app, 0)
        end

        % Callback function
        function RewindButtonPushed(app, event)
            DecreasePlaybackSpeed(app)
        end

        % Button pushed function: MarkCompleteButton
        function MarkCompleteButtonPushed(app, event)
            app.ReviewClipTable = app.ReviewClipTable.MarkComplete();
            
            if app.VerboseLevel > 0
                fprintf('Clip %d marked complete\n', app.CurrentClipNumber )
            end            
            app.SelectedClipNumber = [];%app.ReviewClipTable.GetSelectedClipNumber(1, 'incomplete');
            app.CurrentClipNumber = [];
            app.SetCurrentBehavior([]);

            UpdateAnnotationStatusTables(app)
            app.CreateEthogramPlot()
            cla(app.UIAxes)

            removeStyle(app.IncompleteTable)
            removeStyle(app.CompleteTable)
            set(app.MarkCompleteButton, 'Enable', 'off')
            set(app.MarkIncompleteButton, 'Enable', 'off')            
        end

        % Button pushed function: MarkIncompleteButton
        function MarkIncompleteButtonButtonPushed(app, event)
            if app.VerboseLevel > 0
                fprintf('Clip %d marked incomplete\n', app.CurrentClipNumber )
            end
            app.ReviewClipTable = app.ReviewClipTable.MarkIncomplete();
            
            app.CurrentClipNumber = [];
            app.SelectedClipNumber = [];

            app.SetCurrentBehavior([]);

            UpdateAnnotationStatusTables(app)
            app.CreateEthogramPlot()
            cla(app.UIAxes)

            removeStyle(app.IncompleteTable)
            removeStyle(app.CompleteTable)
            set(app.MarkCompleteButton, 'Enable', 'off')
            set(app.MarkIncompleteButton, 'Enable', 'off')
        end

        % Button pushed function: SaveProgressButton
        function SaveProgressButtonPushed(app, event)
            SaveProgress(app)
        end

        % Cell selection callback: BehaviorLabelsTable
        function BehaviorLabelsTableCellSelection(app, event)
            removeStyle(app.BehaviorLabelsTable)
            
            indices = event.Indices;
            app.AllowKeyPressControl = false;
            app.BehaviorUITableSelection = indices;

            s = uistyle('BackgroundColor', '#80bfff');
            addStyle(app.BehaviorLabelsTable, s, 'row', indices(1))

            set(app.BehaviorLabelsTable, 'Selection', [])

        end

        % Cell edit callback: BehaviorLabelsTable
        function BehaviorLabelsTableCellEdit(app, event)
            app.SetCurrentBehavior([]);

            newData = event.NewData;            
            selColumn = event.Indices(2);

            keyT = app.Keys.GetKeyTable();

            if selColumn == 2
                existingKeys = keyT.Key;
                
                if any(strcmp(newData, existingKeys))
                    if app.VerboseLevel > 0
                        fprintf('Invalid! Key %s already in use!\n', newData)
                    end

                    app.BehaviorLabelsTable.Data{event.Indices(1), 2} = existingKeys{event.Indices(1)};
                    return
                end

                behav = keyT.Behavior{event.Indices(1)};

                app.ChangeBehaviorKey(behav, newData)
                if app.VerboseLevel > 0
                    fprintf('Key for ''%s'' changed to %s\n', behav, newData)
                end

                return
            end
% 
%             oldBehaviors = keyT.Behavior;
%             oldBehavior = oldBehaviors{event.Indices(1)};
% 
%             if any(strcmp(newData, oldBehaviors))
%                 if app.VerboseLevel > 0
%                     fprintf('Invalid! Name %s already exists!\n', newData)
%                 end
%                 app.BehaviorLabelsTable.Data{event.Indices(1), 1} = oldBehavior;
%                 return
%             end
%            
% 
%             app.RenameBehavior(oldBehavior, newData)
%             if app.VerboseLevel > 0
%                 fprintf('Behavior ''%s'' renamed to ''%s''\n', oldBehavior, newData)
%             end

        end

        % Cell selection callback: IncompleteTable
        function IncompleteTableRowSelection(app, event)
            removeStyle(app.IncompleteTable)
            removeStyle(app.CompleteTable)

            indices = event.Indices;  

            if isempty(indices)
                app.SelectedClipNumber = [];
                return
            end

            currRow = indices(1);
            
            clipNum = Annotator.GetClipNumberByUITableRow(app.IncompleteTable, currRow);
            app.SelectedClipNumber = clipNum;

            s = uistyle('BackgroundColor', '#80bfff');
            addStyle(app.IncompleteTable, s, 'row', currRow)

        end

        % Cell selection callback: CompleteTable
        function CompleteTableCellSelection(app, event)
            removeStyle(app.IncompleteTable)
            removeStyle(app.CompleteTable)

            indices = event.Indices;  

            if isempty(indices)
                app.SelectedClipNumber = [];
                return
            end

            currRow = indices(1);
            clipNum = Annotator.GetClipNumberByUITableRow(app.CompleteTable, currRow);
            app.SelectedClipNumber = clipNum;

            s = uistyle('BackgroundColor', '#80bfff');
            addStyle(app.CompleteTable, s, 'row', currRow)
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)

            mousePos = get(event.Source, 'CurrentPoint');
            panelPos = app.BehaviorlabelsPanel.Position;

            app.AllowKeyPressControl = true;

            if mousePos(1) < panelPos(1)
                return
            end
            % % 
            if mousePos(1) > panelPos(1) + panelPos(3)
                return
            end
            % % 
            if mousePos(2) < panelPos(2)
                return
            end
            % % 
            if mousePos(2) > panelPos(2) + panelPos(4)
                return
            end
            app.AllowKeyPressControl = false;


        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            if ~app.AllowKeyPressControl
                return
            end

            key = event.Key;
            keyT = app.Keys.GetKeyTable();
            keys = keyT.Key;
            
            behavIndex = [];
            for i = 1:length(keys)
                if strcmpi(key, keys(i))
                    behavIndex = i;
                    behaviorLabel = keyT.Behavior{behavIndex};
                end
            end
            
            if ~isempty(behavIndex)
                if ~isempty(app.CurrentBehavior) && strcmpi(app.CurrentBehavior, behaviorLabel)
                    app.SetCurrentBehavior([]);
                else
                    app.SetCurrentBehavior(behaviorLabel);
                end
            end
            

            if strcmp(key, 'rightarrow')
                IncreasePlaybackSpeed(app)
            end
            
            if strcmp(key, 'leftarrow')
                DecreasePlaybackSpeed(app)
            end
            
            if strcmp(key, 'uparrow')
                ChangePlaybackSpeed(app, 0)
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
%             SaveProgress(app)
%             project = DeepActionProject(app.ProjectPath);
%             clipT = app.ReviewClipTable.GetClipTable();
%             project.WriteAnnotationsFromClipTable(clipT);
            delete(app)
        end

        % Button down function: CompleteTable, IncompleteTable
        function IncompleteTableButtonDown(app, event)
            app.AllowKeyPressControl = true;
        end

        % Button down function: BehaviorLabelsTable
        function BehaviorLabelsTableButtonDown(app, event)
            app.AllowKeyPressControl = false;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.Ethogram = uiaxes(app.UIFigure);
            title(app.Ethogram, 'Ethogram')
            app.Ethogram.YLim = [0 3];
            app.Ethogram.XTick = [];
            app.Ethogram.YTick = [];
            app.Ethogram.YTickLabel = '';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.CurrentSpeedLabel = uilabel(app.UIFigure);
            app.CurrentSpeedLabel.Text = 'Playback speed:';
            app.CurrentSpeedText = uilabel(app.UIFigure);
            app.CurrentSpeedText.Text = '0';
            app.CurrentBehaviorText = uilabel(app.UIFigure);
            app.CurrentBehaviorText.Text = 'Current Behavior: ';
            app.CurrentBehaviorLabel = uilabel(app.UIFigure);
            app.CurrentBehaviorLabel.Text = '';
            app.ProjectInformationPanel = uipanel(app.UIFigure);
            app.ProjectInformationPanel.Title = 'Project Information';
            app.TotalFramesLabel = uilabel(app.ProjectInformationPanel);
            app.TotalFramesLabel.Text = '';
            app.TotalFramesText = uilabel(app.ProjectInformationPanel);
            app.TotalFramesText.Text = 'Total Frames:';
            app.AvgClipLengthLabel = uilabel(app.ProjectInformationPanel);
            app.AvgClipLengthLabel.Text = '';
            app.AvgClipLengthText = uilabel(app.ProjectInformationPanel);
            app.AvgClipLengthText.Text = 'Avg. clip length:';
            app.AvgVideoLengthLabel = uilabel(app.ProjectInformationPanel);
            app.AvgVideoLengthLabel.Text = '';
            app.AvgVideoLengthText = uilabel(app.ProjectInformationPanel);
            app.AvgVideoLengthText.Text = 'Avg. video length:';
            app.NumClipsLabel = uilabel(app.ProjectInformationPanel);
            app.NumClipsLabel.Text = '';
            app.NumClipsText = uilabel(app.ProjectInformationPanel);
            app.NumClipsText.Text = 'Clips:';
            app.NumVideosLabel = uilabel(app.ProjectInformationPanel);
            app.NumVideosLabel.Text = '';
            app.NumVideosText = uilabel(app.ProjectInformationPanel);
            app.NumVideosText.Text = 'Videos:';
            app.TotalDurationLabel = uilabel(app.ProjectInformationPanel);
            app.TotalDurationLabel.Text = '';
            app.TotalDurationText = uilabel(app.ProjectInformationPanel);
            app.TotalDurationText.Text = 'Total Duration:';
            app.ProjectNameLabel = uilabel(app.ProjectInformationPanel);
            app.ProjectNameLabel.Text = '';
            app.ProjectNameText = uilabel(app.ProjectInformationPanel);
            app.ProjectNameText.Text = 'Project Name:';
            app.BehaviorlabelsPanel = uipanel(app.UIFigure);
            app.BehaviorlabelsPanel.Title = 'Behavior labels';
            app.BehaviorLabelsTable = uitable(app.BehaviorlabelsPanel);
            app.BehaviorLabelsTable.ColumnName = {'Behavior'; 'Key'};
            app.BehaviorLabelsTable.RowName = {};
            app.BehaviorLabelsTable.ColumnEditable = [false true];
            app.BehaviorLabelsTable.CellEditCallback = createCallbackFcn(app, @BehaviorLabelsTableCellEdit, true);
            app.BehaviorLabelsTable.CellSelectionCallback = createCallbackFcn(app, @BehaviorLabelsTableCellSelection, true);
            app.BehaviorLabelsTable.ButtonDownFcn = createCallbackFcn(app, @BehaviorLabelsTableButtonDown, true);
            app.AnnotationFilesPanel = uipanel(app.UIFigure);
            app.AnnotationFilesPanel.Title = 'Annotation Files';
            app.ActionsPanel = uipanel(app.AnnotationFilesPanel);
            app.ActionsPanel.Title = 'Actions ';
            app.LoadVideoButton = uibutton(app.ActionsPanel, 'push');
            app.LoadVideoButton.ButtonPushedFcn = createCallbackFcn(app, @LoadVideoButtonPushed, true);
            app.LoadVideoButton.Text = 'Load Video';
            app.SaveProgressButton = uibutton(app.ActionsPanel, 'push');
            app.SaveProgressButton.ButtonPushedFcn = createCallbackFcn(app, @SaveProgressButtonPushed, true);
            app.SaveProgressButton.Text = 'Save Progress';
            app.MarkCompleteButton = uibutton(app.ActionsPanel, 'push');
            app.MarkCompleteButton.ButtonPushedFcn = createCallbackFcn(app, @MarkCompleteButtonPushed, true);
            app.MarkCompleteButton.Text = 'Mark Complete';
            app.MarkIncompleteButton = uibutton(app.ActionsPanel, 'push');
            app.MarkIncompleteButton.ButtonPushedFcn = createCallbackFcn(app, @MarkIncompleteButtonButtonPushed, true);
            app.MarkIncompleteButton.Text = 'Mark Incomplete';
            app.AnnotationprogressPanel = uipanel(app.AnnotationFilesPanel);
            app.AnnotationprogressPanel.Title = 'Annotation progress';
            app.CompleteTotalTimeText = uilabel(app.AnnotationprogressPanel);
            app.CompleteTotalTimeText.Text = 'Incomplete time:';
            app.IncompleteTotalTimeText = uilabel(app.AnnotationprogressPanel);
            app.IncompleteTotalTimeText.Text = 'Complete time:';
            app.ProportionAnnotatedText = uilabel(app.AnnotationprogressPanel);
            app.ProportionAnnotatedText.Text = 'Proportion annotated:';
            app.ProportionAnnotatedLabel = uilabel(app.AnnotationprogressPanel);
            app.ProportionAnnotatedLabel.Text = '';
            app.CompleteTotalTimeLabel = uilabel(app.AnnotationprogressPanel);
            app.CompleteTotalTimeLabel.Text = '';
            app.IncompleteTotalTimeLabel = uilabel(app.AnnotationprogressPanel);
            app.IncompleteTotalTimeLabel.Text = '';
            app.EstAccLabel = uilabel(app.AnnotationprogressPanel);
            app.EstAccLabel.Text = '';
            app.EstAccText = uilabel(app.AnnotationprogressPanel);
            app.EstAccText.Text = 'Est. incomplete annotation accuracy:';
            app.IncompleteannotationsLabel = uilabel(app.AnnotationFilesPanel);
            app.IncompleteannotationsLabel.Text = 'Incomplete annotations';
            app.CompleteannotationsLabel = uilabel(app.AnnotationFilesPanel);
            app.CompleteannotationsLabel.Text = 'Complete annotations';
            app.CompleteTable = uitable(app.AnnotationFilesPanel);
            app.CompleteTable.ColumnName = {'Clip No.'; 'Video'; 'Duration'; 'Score'};
            app.CompleteTable.RowName = {};
            app.CompleteTable.CellSelectionCallback = createCallbackFcn(app, @CompleteTableCellSelection, true);
            app.CompleteTable.ButtonDownFcn = createCallbackFcn(app, @IncompleteTableButtonDown, true);
            app.IncompleteTable = uitable(app.AnnotationFilesPanel);
            app.IncompleteTable.ColumnName = {'Clip No.'; 'Video'; 'Duration'; 'Score'};
            app.IncompleteTable.RowName = {};
            app.IncompleteTable.CellSelectionCallback = createCallbackFcn(app, @IncompleteTableRowSelection, true);
            app.IncompleteTable.ButtonDownFcn = createCallbackFcn(app, @IncompleteTableButtonDown, true);
            app.CurrentclipPanel = uipanel(app.UIFigure);
            app.CurrentclipPanel.Title = 'Current clip';
            app.CurrentClipTable = uitable(app.CurrentclipPanel);
            app.CurrentClipTable.ColumnName = {'Clip No.'; 'Video'; 'Duration'; 'Score'};
            app.CurrentClipTable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Annotator(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end