classdef BehaviorTable < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        ProjectNameLabel      matlab.ui.control.Label
        BehaviorsandannotatorkeysforLabel  matlab.ui.control.Label
        ActionsPanel          matlab.ui.container.Panel
        DeletebehaviorButton  matlab.ui.control.Button
        MovelabelLabel        matlab.ui.control.Label
        UpButton              matlab.ui.control.Button
        DownButton            matlab.ui.control.Button
        AddbehaviorButton     matlab.ui.control.Button
        SaveprogressButton    matlab.ui.control.Button
        UITable               matlab.ui.control.Table
    end

    
    properties (Access = private)
        Keys
        SelectedRow
        Project
        AllAnnotations

        VerboseLevel
        
    end
    
    methods (Access = private)
        
        function UpdateTable(app)
            keyT = app.Keys.GetKeyTable();
            app.UITable.Data = keyT;

            % table style
            removeStyle(app.UITable)

            if isempty(app.SelectedRow)
                set(app.UpButton, 'Enable', 'off')
                set(app.DownButton, 'Enable', 'off')
                set(app.DeletebehaviorButton, 'Enable', 'off')
                return
            end

            set(app.DeletebehaviorButton, 'Enable', 'on')

            s = uistyle('BackgroundColor', '#80bfff');
            addStyle(app.UITable, s, 'row', app.SelectedRow)

            if app.SelectedRow ~= 1
                set(app.UpButton, 'Enable', 'on')
            else
                set(app.UpButton, 'Enable', 'off')
            end

            if app.SelectedRow ~= size(keyT,1)
                set(app.DownButton, 'Enable', 'on')
            else
                set(app.DownButton, 'Enable', 'off')
            end

        end
        
        function AddBehavior(app, newBehavior, newKey)
            app.Keys = app.Keys.AddBehavior(newBehavior, newKey);
            app.EditAllAnnotations('AddBehavior', newBehavior)

            app.UpdateTable();
        end

        function EditAllAnnotations(app, action, varargin)
            for i = 1:size(app.AllAnnotations)
                currAnnotT = app.AllAnnotations.Annotations{i};

                if strcmp(action, 'AddBehavior')
                    currAnnotT.Label = addcats(currAnnotT.Label, varargin{1});
                end

                if strcmp(action, 'RenameBehavior')
                    currAnnotT.Label = renamecats(currAnnotT.Label, varargin{1}, varargin{2});
                end
                
                if strcmp(action, 'RemoveBehavior')
                    oldBehavior = varargin{1};
                    isOldBehavior = currAnnotT.Label == categorical({oldBehavior});
                    currAnnotT.Label = removecats(currAnnotT.Label, oldBehavior);
                    currAnnotT.Type(isOldBehavior) = categorical({'UL'});
                end

                app.AllAnnotations.Annotations{i} = currAnnotT;
            end
        end
        
        function ChangeBehaviorKey(app, behavior, newKey)
            app.Keys = app.Keys.ChangeBehaviorKey(behavior, newKey);
        end
        
        function RenameBehavior(app, oldName, newName)
            app.Keys = app.Keys.RenameBehavior(oldName, newName);
            app.EditAllAnnotations('RenameBehavior', oldName, newName)
        end
        
        function RemoveBehavior(app, behavior)
            app.Keys = app.Keys.RemoveBehavior(behavior);
            app.EditAllAnnotations('RemoveBehavior', behavior)
            app.UpdateTable()
        end
        
        function SaveProgress(app)
            app.Keys.Save();
            app.Project.SaveAllAnnotations(app.AllAnnotations)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, project)
            set(app.DeletebehaviorButton, 'Enable', 'off')
            set(app.UpButton, 'Enable', 'off')
            set(app.DownButton, 'Enable', 'off')
            
            app.ProjectNameLabel.Text = project.ProjectName;
            app.Project = DeepActionProject(project.ProjectPath);
            app.Keys = AnnotationKeys(project);
            app.VerboseLevel = project.VerboseLevel;
            app.AllAnnotations = project.LoadAllAnnotations();
            app.UpdateTable()
        end

        % Button pushed function: AddbehaviorButton
        function AddbehaviorButtonPushed(app, event)
            output = inputdlg({'Behavior name:','Key:'},...
              'Add behavior', [1 50; 1 50]);

            newBehavior = output{1};
            newKey = output{2};

            keyT = app.Keys.GetKeyTable();
            existingBehaviors = keyT.Behavior;
            existingKeys= keyT.Key;

            if isempty(newBehavior)
                warning('No behavior specified')
                return
            end

            if isempty(newKey)
                warning('No key specified')
                return
            end

            if any(strcmp(newBehavior, existingBehaviors))
                msg = sprintf('Behavior %s already exists', newBehavior);
                warning(msg)
                return
            end

            if any(strcmp(newKey, existingKeys))
                msg = sprintf('Key %s is already in use', newBehavior);
                warning(msg)
                return
            end
            
            app.AddBehavior(newBehavior, newKey)
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            if isempty(event.Indices)
                app.SelectedRow = [];
            else
                indices = event.Indices;
                app.SelectedRow = indices(1);
            end
            app.UpdateTable()
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            keyT = app.Keys.GetKeyTable();

            selRow = event.Indices(1);
            selColumn = event.Indices(2);
            newData = event.NewData;

            if selColumn == 2                
                if any(strcmp(newData, keyT.Key))
                    warning(sprintf('Invalid! Key %s already in use\n', newData))

                    app.UITable.Data{selRow, 2} = keyT.Key(selRow);
                    return
                end

                if isempty(newData)
                    warning(sprintf('Invalid! No key entered\n'))

                    app.UITable.Data{selRow, 2} = keyT.Key(selRow);
                    return
                end

                if length(newData) > 1
                    warning(sprintf('Invalid! Entry more than one character\n'))

                    app.UITable.Data{selRow, 2} = keyT.Key(selRow);
                    return
                end

                app.ChangeBehaviorKey(keyT.Behavior{selRow}, newData)
                if app.VerboseLevel > 0
                    fprintf('Key for ''%s'' changed to %s\n', keyT.Behavior{selRow}, newData)
                end

                return
            end

            selBehavior = keyT.Behavior{selRow};
            if any(strcmp(newData, keyT.Behavior))
                warning(sprintf('Invalid! Behavior %s already exists\n', newData))
                app.UITable.Data{selRow, 1} = keyT.Behavior(selRow);
                return
            end

            app.RenameBehavior(keyT.Behavior{selRow}, newData)
            if app.VerboseLevel > 0
                fprintf('Behavior ''%s'' renamed to ''%s''\n', keyT.Behavior{selRow}, newData)
            end
        end

        % Button pushed function: DeletebehaviorButton
        function DeletebehaviorButtonPushed(app, event)
            keyT = app.Keys.GetKeyTable();
            behavToDelete = keyT.Behavior{app.SelectedRow};

            msg = sprintf('Are you sure you want to delete behavior ''%s''?', behavToDelete);
            
            selection = uiconfirm(app.UIFigure, msg, 'Delete behavior',...
                        'Icon', 'warning', 'DefaultOption', 2);

            if ~strcmp(selection, 'OK')
                return
            end
 
            app.RemoveBehavior(behavToDelete)

            if app.VerboseLevel > 0
                fprintf('Behavior ''%s'' removed \n', behavToDelete)
            end
        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)
            newDisplayData = app.UITable.DisplayData;
            disp(newDisplayData)
        end

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
            if app.SelectedRow == 1
                return
            end
            
            keyT = app.Keys.GetKeyTable();
            selBehavior = keyT.Behavior{app.SelectedRow};
            app.Keys = app.Keys.ShiftBehaviorUp(selBehavior);

            app.SelectedRow = app.SelectedRow - 1;
            app.UpdateTable();
        end

        % Button pushed function: DownButton
        function DownButtonPushed(app, event)
            keyT = app.Keys.GetKeyTable();
            if app.SelectedRow == size(keyT, 1)
                return
            end


            keyT = app.Keys.GetKeyTable();
            selBehavior = keyT.Behavior{app.SelectedRow};
            app.Keys = app.Keys.ShiftBehaviorDown(selBehavior);

            app.SelectedRow = app.SelectedRow + 1;

            app.UpdateTable();
        end

        % Button pushed function: SaveprogressButton
        function SaveprogressButtonPushed(app, event)
            app.SaveProgress();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Behavior'; 'Key'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.ActionsPanel = uipanel(app.UIFigure);
            app.ActionsPanel.Title = 'Actions';
            app.SaveprogressButton = uibutton(app.ActionsPanel, 'push');
            app.SaveprogressButton.ButtonPushedFcn = createCallbackFcn(app, @SaveprogressButtonPushed, true);
            app.SaveprogressButton.Text = 'Save progress';
            app.AddbehaviorButton = uibutton(app.ActionsPanel, 'push');
            app.AddbehaviorButton.ButtonPushedFcn = createCallbackFcn(app, @AddbehaviorButtonPushed, true);
            app.AddbehaviorButton.Text = 'Add behavior';
            app.DownButton = uibutton(app.ActionsPanel, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.DownButton.Text = '⬇';
            app.UpButton = uibutton(app.ActionsPanel, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Text = '⬆';
            app.MovelabelLabel = uilabel(app.ActionsPanel);
            app.MovelabelLabel.Text = 'Movelabel';
            app.DeletebehaviorButton = uibutton(app.ActionsPanel, 'push');
            app.DeletebehaviorButton.ButtonPushedFcn = createCallbackFcn(app, @DeletebehaviorButtonPushed, true);
            app.DeletebehaviorButton.Text = 'Delete behavior';
            app.BehaviorsandannotatorkeysforLabel = uilabel(app.UIFigure);
            app.BehaviorsandannotatorkeysforLabel.Text = 'Behaviors and annotator keys for:';
            app.ProjectNameLabel = uilabel(app.UIFigure);
            app.ProjectNameLabel.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BehaviorTable(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end